classdef ClasificacionLineal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClasificacionlinealMetodosnumericosUIFigure  matlab.ui.Figure
        ArchivoMenu                    matlab.ui.container.Menu
        SalirMenu                      matlab.ui.container.Menu
        AyudaMenu                      matlab.ui.container.Menu
        InformacionMenu                matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        ProcesamientodelosdatosTab     matlab.ui.container.Tab
        DatosPanel                     matlab.ui.container.Panel
        ClaseATable                    matlab.ui.control.Table
        ClaseBTable                    matlab.ui.control.Table
        ClaseBLabel                    matlab.ui.control.Label
        ClaseALabel                    matlab.ui.control.Label
        AgregarpuntoButton             matlab.ui.control.Button
        EliminarpuntoButton            matlab.ui.control.Button
        GraficaPanel                   matlab.ui.container.Panel
        Plotter                        matlab.ui.control.UIAxes
        IteracionactualEditFieldLabel  matlab.ui.control.Label
        IterationEditField             matlab.ui.control.NumericEditField
        EjecutarSwitchLabel            matlab.ui.control.Label
        EjecutarSwitch                 matlab.ui.control.ToggleSwitch
        ParametrosPanel                matlab.ui.container.Panel
        MaximodeiteracionesSpinnerLabel  matlab.ui.control.Label
        MaxIteSpinner                  matlab.ui.control.Spinner
        MargendeerrorEditFieldLabel    matlab.ui.control.Label
        DeltaEditField                 matlab.ui.control.NumericEditField
        TasadeaprendizajeSliderLabel   matlab.ui.control.Label
        LearningRateSlider             matlab.ui.control.Slider
        Vector1EditField               matlab.ui.control.NumericEditField
        Vector2EditField               matlab.ui.control.NumericEditField
        Vector3EditField               matlab.ui.control.NumericEditField
        DiscriminantelinealLabel       matlab.ui.control.Label
        EcuacionLabel                  matlab.ui.control.Label
        VectorLabel                    matlab.ui.control.Label
        EquationLabel                  matlab.ui.control.Label
        NoejecutadoLampLabel           matlab.ui.control.Label
        LinearLamp                     matlab.ui.control.Lamp
        EnejecucionLampLabel           matlab.ui.control.Label
        ExecutionLamp                  matlab.ui.control.Lamp
        TeoriadelaaplicacionTab        matlab.ui.container.Tab
        ClasificacionlinealLabel       matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        function Plot(app)
            x1 = app.ClaseATable.Data(:, 1);
            y1 = app.ClaseATable.Data(:, 2);
            
            x2 = app.ClaseBTable.Data(:, 1);
            y2 = app.ClaseBTable.Data(:, 2);
            
            x3 = linspace(min([x1; x2])-1,max([x1; x2])+1);
            y3 = - app.Vector2EditField.Value / app.Vector3EditField.Value * x3 + app.Vector1EditField.Value / app.Vector3EditField.Value;
            
            p1 = scatter(app.Plotter, x1, y1, 75, 'blue', 'filled', '^');
            hold(app.Plotter, 'on');
            p2 = scatter(app.Plotter, x2, y2, 75, 'red', 'filled', 'square');
            p3 = plot(app.Plotter, x3, y3,'-black');
            xline(app.Plotter, 0);
            yline(app.Plotter, 0);
            hold(app.Plotter, 'off');
            %axis(app.Plotter, [min([x1; x2])-1 max([x1; x2])+1 min([y1; y2])-1 max([y1; y2])+1]);
            legend(app.Plotter, [p1 p2 p3], {'Clase A', 'Clase B', 'Discriminante lineal'});
        end
        
        function [a, b, e] = HosKashyap(app, a, b, Y, eta)
            e = Y*a - b;
            b = b + eta * (e + abs(e));
            a = (Y' * Y)^-1 * Y' * b;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: SalirMenu
        function OnClickMenuSalir(app, event)
            delete(app);
        end

        % Menu selected function: InformacionMenu
        function OnClickMenuInformacion(app, event)
            disp(newline + "Clasificacion Lineal - Metodos numericos" + newline);
            disp("Juan Diego Preciado Mahecha");
            disp("Jorge Aurelio Morales Manrique");
            disp("Ricardo Andres Calvo Mendez");
        end

        % Button pushed function: AgregarpuntoButton
        function OnClickAgregarPunto(app, event)
            Data = app.ClaseATable.Data;
            Data(size(Data, 1) + 1, 1) = 0;
            Data(size(Data, 1), 2) = 0;
            app.ClaseATable.Data = Data;
            
            Data = app.ClaseBTable.Data;
            Data(size(Data, 1) + 1, 1) = 0;
            Data(size(Data, 1), 2) = 0;
            app.ClaseBTable.Data = Data;
        end

        % Button pushed function: EliminarpuntoButton
        function OnClickEliminarPunto(app, event)
            Data = app.ClaseATable.Data;
            Data = Data(1 : size(Data, 1) - 1, 1 : 2);
            app.ClaseATable.Data = Data;
            
            Data = app.ClaseBTable.Data;
            Data = Data(1 : size(Data, 1) - 1, 1 : 2);
            app.ClaseBTable.Data = Data;
        end

        % Cell edit callback: ClaseATable, ClaseBTable
        function OnEditClass(app, event)
            Plot(app);
        end

        % Value changed function: EjecutarSwitch
        function Executer(app, event)
            value = app.EjecutarSwitch.Value;
            if value == "On"
                Y = [ones(size(app.ClaseATable.Data, 1), 1) app.ClaseATable.Data];
                Y = [Y; -ones(size(app.ClaseBTable.Data, 1), 1) -app.ClaseBTable.Data];
                a = [app.Vector1EditField.Value; app.Vector2EditField.Value ; app.Vector3EditField.Value];
                b = ones(size(Y, 1) , 1);
                k = 1;
                e = inf;
                eta = app.LearningRateSlider.Value;
                while k <= app.MaxIteSpinner.Value && app.EjecutarSwitch.Value == "On"
                    [a, b, e] = HosKashyap(app, a, b, Y, eta);
                    app.Vector1EditField.Value = a(1);
                    app.Vector2EditField.Value = a(2);
                    app.Vector3EditField.Value = a(3);
                    Plot(app);
                    k = k + 1;
                end
                app.EjecutarSwitch.Value = "Off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClasificacionlinealMetodosnumericosUIFigure = uifigure('Visible', 'off');
            app.ClasificacionlinealMetodosnumericosUIFigure.Name = 'Clasificacion lineal - Metodos numericos';
            app.ArchivoMenu = uimenu(app.ClasificacionlinealMetodosnumericosUIFigure);
            app.ArchivoMenu.Text = 'Archivo';
            app.SalirMenu = uimenu(app.ArchivoMenu);
            app.SalirMenu.MenuSelectedFcn = createCallbackFcn(app, @OnClickMenuSalir, true);
            app.SalirMenu.Accelerator = 'X';
            app.SalirMenu.Text = 'Salir';
            app.AyudaMenu = uimenu(app.ClasificacionlinealMetodosnumericosUIFigure);
            app.AyudaMenu.Text = 'Ayuda';
            app.InformacionMenu = uimenu(app.AyudaMenu);
            app.InformacionMenu.MenuSelectedFcn = createCallbackFcn(app, @OnClickMenuInformacion, true);
            app.InformacionMenu.Text = 'Informacion';
            app.TabGroup = uitabgroup(app.ClasificacionlinealMetodosnumericosUIFigure);
            app.ProcesamientodelosdatosTab = uitab(app.TabGroup);
            app.ProcesamientodelosdatosTab.Title = 'Procesamiento de los datos';
            app.DatosPanel = uipanel(app.ProcesamientodelosdatosTab);
            app.DatosPanel.Title = 'Datos';
            app.ClaseATable = uitable(app.DatosPanel);
            app.ClaseATable.ColumnName = {'X'; 'Y'};
            app.ClaseATable.RowName = {};
            app.ClaseATable.ColumnSortable = [true false];
            app.ClaseATable.ColumnEditable = true;
            app.ClaseATable.CellEditCallback = createCallbackFcn(app, @OnEditClass, true);
            app.ClaseBTable = uitable(app.DatosPanel);
            app.ClaseBTable.ColumnName = {'X'; 'Y'};
            app.ClaseBTable.RowName = {};
            app.ClaseBTable.ColumnSortable = [true false];
            app.ClaseBTable.ColumnEditable = true;
            app.ClaseBTable.CellEditCallback = createCallbackFcn(app, @OnEditClass, true);
            app.ClaseBLabel = uilabel(app.DatosPanel);
            app.ClaseBLabel.Text = 'Clase B';
            app.ClaseALabel = uilabel(app.DatosPanel);
            app.ClaseALabel.Text = 'Clase A';
            app.AgregarpuntoButton = uibutton(app.DatosPanel, 'push');
            app.AgregarpuntoButton.ButtonPushedFcn = createCallbackFcn(app, @OnClickAgregarPunto, true);
            app.AgregarpuntoButton.Text = 'Agregar punto';
            app.EliminarpuntoButton = uibutton(app.DatosPanel, 'push');
            app.EliminarpuntoButton.ButtonPushedFcn = createCallbackFcn(app, @OnClickEliminarPunto, true);
            app.EliminarpuntoButton.Text = 'Eliminar punto';
            app.GraficaPanel = uipanel(app.ProcesamientodelosdatosTab);
            app.GraficaPanel.Title = 'Grafica';
            app.Plotter = uiaxes(app.GraficaPanel);
            title(app.Plotter, 'Grafica de los puntos')
            xlabel(app.Plotter, 'X')
            ylabel(app.Plotter, 'Y')
            app.IteracionactualEditFieldLabel = uilabel(app.ProcesamientodelosdatosTab);
            app.IteracionactualEditFieldLabel.Text = 'Iteracion actual';
            app.IterationEditField = uieditfield(app.ProcesamientodelosdatosTab, 'numeric');
            app.IterationEditField.ValueDisplayFormat = '%.0f';
            app.IterationEditField.Editable = 'off';
            app.EjecutarSwitchLabel = uilabel(app.ProcesamientodelosdatosTab);
            app.EjecutarSwitchLabel.Text = 'Ejecutar';
            app.EjecutarSwitch = uiswitch(app.ProcesamientodelosdatosTab, 'toggle');
            app.EjecutarSwitch.ValueChangedFcn = createCallbackFcn(app, @Executer, true);
            app.ParametrosPanel = uipanel(app.ProcesamientodelosdatosTab);
            app.ParametrosPanel.Title = 'Parametros';
            app.MaximodeiteracionesSpinnerLabel = uilabel(app.ParametrosPanel);
            app.MaximodeiteracionesSpinnerLabel.Text = 'Maximo de iteraciones';
            app.MaxIteSpinner = uispinner(app.ParametrosPanel);
            app.MaxIteSpinner.Value = 200;
            app.MargendeerrorEditFieldLabel = uilabel(app.ParametrosPanel);
            app.MargendeerrorEditFieldLabel.Text = 'Margen de error';
            app.DeltaEditField = uieditfield(app.ParametrosPanel, 'numeric');
            app.DeltaEditField.Value = 0.01;
            app.TasadeaprendizajeSliderLabel = uilabel(app.ParametrosPanel);
            app.TasadeaprendizajeSliderLabel.Text = 'Tasa de aprendizaje';
            app.LearningRateSlider = uislider(app.ParametrosPanel);
            app.LearningRateSlider.Limits = [0 1];
            app.LearningRateSlider.Value = 0.9;
            app.Vector1EditField = uieditfield(app.ParametrosPanel, 'numeric');
            app.Vector1EditField.Value = 1;
            app.Vector2EditField = uieditfield(app.ParametrosPanel, 'numeric');
            app.Vector2EditField.Value = 1;
            app.Vector3EditField = uieditfield(app.ParametrosPanel, 'numeric');
            app.Vector3EditField.Value = 1;
            app.DiscriminantelinealLabel = uilabel(app.ParametrosPanel);
            app.DiscriminantelinealLabel.Text = 'Discriminante lineal';
            app.EcuacionLabel = uilabel(app.ParametrosPanel);
            app.EcuacionLabel.Text = 'Ecuacion:';
            app.VectorLabel = uilabel(app.ParametrosPanel);
            app.VectorLabel.Text = 'Vector:';
            app.EquationLabel = uilabel(app.ParametrosPanel);
            app.EquationLabel.Text = 'y = - x - 1';
            app.NoejecutadoLampLabel = uilabel(app.ParametrosPanel);
            app.NoejecutadoLampLabel.Text = 'No ejecutado';
            app.LinearLamp = uilamp(app.ParametrosPanel);
            app.EnejecucionLampLabel = uilabel(app.ProcesamientodelosdatosTab);
            app.EnejecucionLampLabel.Text = 'En ejecucion';
            app.ExecutionLamp = uilamp(app.ProcesamientodelosdatosTab);
            app.TeoriadelaaplicacionTab = uitab(app.TabGroup);
            app.TeoriadelaaplicacionTab.Title = 'Teoria de la aplicacion';
            app.ClasificacionlinealLabel = uilabel(app.ClasificacionlinealMetodosnumericosUIFigure);
            app.ClasificacionlinealLabel.Text = 'Clasificacion lineal';
            % Show the figure after all components are created
            app.ClasificacionlinealMetodosnumericosUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClasificacionLineal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClasificacionlinealMetodosnumericosUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClasificacionlinealMetodosnumericosUIFigure)
        end
    end
end